classdef ColorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        MonochromaticviewLabel        matlab.ui.control.Label
        DichromaticViewLabel          matlab.ui.control.Label
        AnomalousTrichromacyLabel     matlab.ui.control.Label
        IntensitySlider               matlab.ui.control.Slider
        IntensitySliderLabel          matlab.ui.control.Label
        TrichromaticviewButtonGroup   matlab.ui.container.ButtonGroup
        TrichromaticviewLabel         matlab.ui.control.Label
        BlueConeMonochromacyButton_2  matlab.ui.control.RadioButton
        MonochromacyAchromatopsiaButton_2  matlab.ui.control.RadioButton
        BBlindTritanopiaButton_2      matlab.ui.control.RadioButton
        GBlindDeuteranopiaButton_2    matlab.ui.control.RadioButton
        RBlindProtanomalyButton_2     matlab.ui.control.RadioButton
        BweakTritanomalyButton_2      matlab.ui.control.RadioButton
        GweakDeuteranomalyButton_2    matlab.ui.control.RadioButton
        RweakProtanomalyButton_2      matlab.ui.control.RadioButton
        NormalButton                  matlab.ui.control.RadioButton
        ResetButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        WebCamButton                  matlab.ui.control.Button
        UploadButton                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
         OriginalImage % Stores the uploaded image
         SimulatedImage % Stores the simulated image
        Property4 % Description
        %%%%%%%%% Helper Function %%%%%%%%
    end
    
    methods (Access = private)
        
        function simulatedImage = applyRWeakProtanomaly(app)
             % Logic to simulate Protanomaly (Red-Weak)
    % Adjust the red channel to mimic reduced sensitivity
      simulatedImage = app.OriginalImage;
      simulatedImage(:, :, 1) = 0.5 * simulatedImage(:, :, 1) + 0.5 * simulatedImage(:, :, 2); % Blend red with green
        end
       function simulatedImage = applyGWeakDeuteranomaly(app)
    % Logic to simulate Deuteranomaly (Green-Weak)
    % Adjust the green channel to mimic reduced sensitivity
    simulatedImage = app.OriginalImage;
    simulatedImage(:, :, 2) = 0.5 * simulatedImage(:, :, 2) + 0.5 * simulatedImage(:, :, 1); % Blend green with red
       end
       function simulatedImage = applyBWeakTritanomaly(app)
    % Logic to simulate Tritanomaly (Blue-Weak)
    % Adjust the blue channel to mimic reduced sensitivity
    simulatedImage = app.OriginalImage;
    simulatedImage(:, :, 3) = 0.5 * simulatedImage(:, :, 3) + 0.5 * simulatedImage(:, :, 2); % Blend blue with green
       end
      % function simulatedImage = applyRBlindProtanopia(app)
    % Logic to simulate Protanopia (Red-Blind)
    % Remove the red channel and simulate with green and blue
   % simulatedImage = app.OriginalImage;
    %simulatedImage(:, :, 1) = 0.299 * simulatedImage(:, :, 2) + 0.701 * simulatedImage(:, :, 3); % Replace red with weighted green and blue
%function simulatedImage = applyRBlindProtanopia(app)
    % Logic to simulate Protanopia (Red-Blind) with red channel at 2% and preserving visibility
    
    % Get the original image
 %   simulatedImage = app.OriginalImage;
    
    % Reduce the red channel to 2% of its original intensity
 %   simulatedImage(:, :, 1) = 0.02 * simulatedImage(:, :, 1); % Keep red channel at 2%
    
    % Normalize the green and blue channels
    % Instead of reducing them too much, we normalize them to preserve visibility
    %simulatedImage(:, :, 2) = simulatedImage(:, :, 2); % Keep green as is
    %simulatedImage(:, :, 3) = simulatedImage(:, :, 3); % Keep blue as is
    
    % Ensure all pixel values are within the valid range [0, 255]
   % simulatedImage = uint8(max(min(simulatedImage, 255), 0)); % Clip values to [0, 255]
    
    % Adjust overall brightness to ensure the image isn't too dark
    % We can increase brightness by scaling all channels slightly without disturbing the Protanopia effect
  %  brightness_factor = 1.2; % Increase brightness slightly
 %   simulatedImage = uint8(min(double(simulatedImage) * brightness_factor, 255));
%end

%%%%%%%%%%%%R-Blind%%%%%%%%%%%%%%5
function simulatedImage = applyRBlindProtanopia(app)
    % Simulates Protanopia (Red-Blindness) with reduced red channel,
    % balanced green and blue, lowered contrast, and increased brightness.
    
    % Get the original image
    simulatedImage = double(app.OriginalImage); % Convert to double for processing

    % Reduce the red channel to 2% of its original intensity
    simulatedImage(:, :, 1) = 0.02 * simulatedImage(:, :, 1); % Keep red channel at 2%

    % Adjust green and blue channels to balance visibility
    simulatedImage(:, :, 2) = 0.7 * simulatedImage(:, :, 2); % Scale down green
    simulatedImage(:, :, 3) = 0.7 * simulatedImage(:, :, 3); % Scale down blue

    % Lower contrast using a contrast factor
    contrastFactor = 0.85; % Adjust contrast (value < 1 reduces contrast)
    simulatedImage = (simulatedImage - 127.5) * contrastFactor + 127.5;

    % Increase overall brightness
    brightnessBoost = 30; % Brightness increase value
    simulatedImage = simulatedImage + brightnessBoost;

    % Ensure all pixel values are within the valid range [0, 255]
    simulatedImage = max(min(simulatedImage, 255), 0); % Clip values

    % Convert back to uint8 for display and usage
    simulatedImage = uint8(simulatedImage);
end

    
%%%%%%%%%%%%%%%%%%%%%%


   % end
%function simulatedImage = applyGBlindDeuteranopia(app)
 function simulatedImage = applyGBlindDeuteranopia(app)
    % Transformation matrix for Deuteranopia (Green-Blind)
    T = [
        0.567, 0.433, 0;      % Red channel transformation
        0.558, 0.442, 0;      % Green channel transformation
        0, 0.242, 0.758       % Blue channel transformation
    ];

    % Get the original image from the app
    originalImage = app.OriginalImage;

    % Convert the image to double for processing
    originalImage = im2double(originalImage);

    % Reshape the image into a 2D array (MxNx3 -> (M*N)x3)
    [rows, cols, ~] = size(originalImage);
    reshapedImage = reshape(originalImage, [], 3);

    % Apply the color transformation matrix
    transformedImage = reshapedImage * T';

    % Clip values to ensure they are in the valid range [0, 1]
    transformedImage = max(0, min(1, transformedImage));

    % Reshape back to the original image size
    simulatedImage = reshape(transformedImage, rows, cols, 3);

    % Convert the image back to uint8 if the original image was uint8
    simulatedImage = im2uint8(simulatedImage);
end


%function simulatedImage = applyBBlindTritanopia(app)
   function simulatedImage = applyBBlindTritanopia(app)
    % Transformation matrix for Tritanopia (Blue-Blind)
    T = [
        0.95, 0.05, 0;        % Red channel transformation
        0, 0.433, 0.567;      % Green channel transformation
        0, 0.475, 0.525       % Blue channel transformation
    ];

    % Get the original image from the app
    originalImage = app.OriginalImage;

    % Convert the image to double for processing
    originalImage = im2double(originalImage);

    % Reshape the image into a 2D array (MxNx3 -> (M*N)x3)
    [rows, cols, ~] = size(originalImage);
    reshapedImage = reshape(originalImage, [], 3);

    % Apply the color transformation matrix
    transformedImage = reshapedImage * T';

    % Clip values to ensure they are in the valid range [0, 1]
    transformedImage = max(0, min(1, transformedImage));

    % Reshape back to the original image size
    simulatedImage = reshape(transformedImage, rows, cols, 3);

    % Convert the image back to uint8 if the original image was uint8
    simulatedImage = im2uint8(simulatedImage);
end

%end
function simulatedImage = applyMonochromacy(app)
    % Convert the original image to grayscale
    simulatedImage = rgb2gray(app.OriginalImage);
    % Convert back to RGB for consistency in display
    simulatedImage = cat(3, simulatedImage, simulatedImage, simulatedImage);
end
%%%%%%%%%%%%%%%%%%%
function simulatedImage = applyBlueConeMonochromacy(app)
    % Logic to simulate Blue Cone Monochromacy
   % Read the input image
%input_image = imread('app.OriginalImage'); % Replace with the path to your input image

% Convert image to double for processing
app.OriginalImage = double(app.OriginalImage);

% Normalize each channel independently (Red, Green, Blue)
simulatedImage = app.OriginalImage;

% Process each channel (R, G, B) to normalize
for channel = 1:3
    % Calculate the histogram of the input image for the current channel
    input_hist = imhist(uint8(app.OriginalImage(:,:,channel)));
    
    % Compute the cumulative distribution function (CDF) for the input image
    input_cdf = cumsum(input_hist) / numel(app.OriginalImage(:,:,channel));
    
    % Create a uniform distribution CDF (target)
    uniform_cdf = linspace(0, 1, 256);
    
    % Map the input CDF to a uniform CDF using the inverse CDF
    mapping = zeros(256, 1);
    for i = 1:256
        [~, idx] = min(abs(input_cdf(i) - uniform_cdf));
        mapping(i) = idx - 1; % Adjust index to be 0-based
    end
    
    % Apply the mapping to the input image for the current channel
    simulatedImage(:,:,channel) = mapping(uint8(app.OriginalImage(:,:,channel)) + 1);
end

% Clip the output to ensure all values are in the valid range [0, 255]
simulatedImage = max(min(simulatedImage, 255), 0);

% Convert the output image back to uint8
simulatedImage = uint8(simulatedImage);

% Decrease the color saturation by blending with grayscale image (30% desaturation)
gray_image = rgb2gray(simulatedImage); % Convert output image to grayscale
gray_image = repmat(gray_image, [1, 1, 3]); % Convert back to a 3-channel image

% Blend the color image and grayscale image with 30% desaturation
simulatedImage = uint8(0.7 * double(simulatedImage) + 0.3 * double(gray_image));

% Convert the desaturated image back to uint8
simulatedImage = uint8(simulatedImage);

% Display the result
%imshow(simulatedImage);
%title('Desaturated and Normalized Image');


    % Reshape back to the original image size
   % simulatedImage = reshape(simulatedImage, rows, cols, 3);
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
           % Upload Button callback
          %function uploadButtonPushed(app, event)
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'});
    if isequal(file, 0)
        return; % User canceled
    end
    img = imread(fullfile(path, file)); % Read the image
    app.OriginalImage = img; % Store it in app properties
    imshow(img, 'Parent', app.UIAxes); % Display it in the axes


        end

        % Button pushed function: WebCamButton
        function WebCamButtonPushed(app, event)
           % function WebCamButtonPushed(app, event)
         cam = webcam; % Connect to webcam
         app.OriginalImage = snapshot(cam); % Capture an image
         clear cam; % Disconnect from webcam
         imshow(app.OriginalImage, 'Parent', app.UIAxes); % Display the image

        end

        % Selection changed function: TrichromaticviewButtonGroup
        function TrichromaticviewButtonGroupSelectionChanged(app, event)
         
% Selection changed function for Trichromatic View Button Group
%function TrichromaticviewButtonGroupSelectionChanged(app, event)
    % Check if an image has been uploaded or captured
    if isempty(app.OriginalImage)
        uialert(app.UIFigure, 'Please upload or capture an image first.', 'Error');
        return; % Exit if no image is available
    end

    % Get the selected button text
    selectedButton = app.TrichromaticviewButtonGroup.SelectedObject.Text;

    % Initialize simulated image as the original image
    simulatedImage = app.OriginalImage;

    % Call the appropriate function based on the selected button
    switch selectedButton
        case 'Normal'
            % No changes, keep the original image
            simulatedImage = app.OriginalImage;

        case 'R-weak/Protanomaly'
            % Call function to apply Protanomaly simulation
            simulatedImage = applyRWeakProtanomaly(app);

        case 'G-weak/Deuteranomaly'
            % Call function to apply Deuteranomaly simulation
            simulatedImage = applyGWeakDeuteranomaly(app);

        case 'B-weak/Tritanomaly'
            % Call function to apply Tritanomaly simulation
            simulatedImage = applyBWeakTritanomaly(app);

        case 'R-Blind/Protanomaly'
            % Call function to apply Protanopia simulation
            simulatedImage = applyRBlindProtanopia(app);

        case 'G-Blind/Deuteranopia'
            % Call function to apply Deuteranopia simulation
            simulatedImage = applyGBlindDeuteranopia(app);

        case 'B-Blind/Tritanopia'
            % Call function to apply Tritanopia simulation
            simulatedImage = applyBBlindTritanopia(app);

        case 'Monochromacy/Achromatopsia'
            % Call function to apply Monochromacy simulation
            simulatedImage = applyMonochromacy(app);

        case 'Blue Cone Monochromacy'
            % Call function to apply Blue Cone Monochromacy simulation
            simulatedImage = applyBlueConeMonochromacy(app);

        otherwise
            % Default case (in case of unexpected selection)
            simulatedImage = app.OriginalImage;
    end

    % Store the simulated image in the app object
    app.SimulatedImage = simulatedImage;

    % Display the simulated image in the corresponding axes
    imshow(simulatedImage, 'Parent', app.UIAxes2);

        end

        % Value changed function: IntensitySlider
        function IntensitySliderValueChanged(app, event)
            %value = app.IntensitySlider.Value;
           % Callback for the Intensity Slider
%function intensityBarValueChanged(app, event)
    % Get the slider value (0 to 100)
    intensityValue = app.IntensitySlider.Value;

    % Normalize the intensity to a scale of 0 to 2
    % 1 means no change, <1 dims the image, >1 brightens it
    normalizedIntensity = intensityValue / 50; 

    % Get the original simulated image
    originalImage = app.SimulatedImage; % Assuming you store the original image in the app

    % Adjust the brightness
    if isfloat(originalImage) % For [0, 1] normalized images
        brightenedImage = originalImage * normalizedIntensity;
        % Clip values to stay within [0, 1]
        brightenedImage = min(max(brightenedImage, 0), 1);
    else % For uint8 images
        brightenedImage = originalImage * normalizedIntensity;
        % Clip values to stay within [0, 255]
        brightenedImage = uint8(min(max(brightenedImage, 0), 255));
    end

    % Update the simulated image display
    imshow(brightenedImage, 'Parent', app.UIAxes2);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           

%%%%%%%%%%%%%%
% Callback for the Reset Button
%function ResetButtonPushed(app, event)
    % Reset the intensity slider to its default value
    app.IntensitySlider.Value = 50;

    % Clear the uploaded image and simulated image
    cla(app.UIAxes); % Clears the uploaded/original image axes
    cla(app.UIAxes2); % Clears the simulated image axes

    % Reset the image properties (if stored in app properties)
    app.OriginalImage = []; % Clear the uploaded image
    app.SimulatedImage = []; % Clear the simulated image

    % Reset any other settings or UI components
   app.TrichromaticviewButtonGroup.SelectedObject = app.NormalButton; % Set Trichromatic View to Normal
    % Add similar lines if other groups or radio buttons need resetting

    % Display a default placeholder (optional)
   % text(app.UIAxes, 0.5, 0.5, 'Original Image', ...
    %    'HorizontalAlignment', 'center', 'FontSize', 14);
    %text(app.UIAxes2, 0.5, 0.5, 'Simulated Image', ...
     %   'HorizontalAlignment', 'center', 'FontSize', 14);

    % Notify user
    uialert(app.UIFigure, 'App has been reset to default settings!', 'Reset Successful');


            %%%%%%%%%%%
            % Callback for the Reset Button
%function ResetButtonPushed(app, event)
    % Reset the intensity slider to the default value (e.g., 50)
    %-app.IntensitySlider.Value = 50;

    % Reset the simulated image to its default state (or blank)
   %- cla(app.UIAxes2); % Clears the simulated image axes
  %-  imshow(app.OriginalImage, 'Parent', app.UIAxes2); % Reload the original image

    % Reset any other settings or UI components (if applicable)
    % For example, resetting radio buttons to "Normal"
%-  app.TrichromaticviewButtonGroup.SelectedObject = app.NormalButton; % Replace with actual button handles
%app.TrichromaticviewButtonGroup.SelectedObject
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           % Callback for the Save Button
%function SaveButtonPushed(app, event)
    % Check if there is a simulated image to save
    if isempty(app.SimulatedImage)
        uialert(app.UIFigure, 'No simulated image to save.', 'Error');
        return;
    end

    % Open a save dialog for the user to choose the file location
    [file, path] = uiputfile({'*.png'; '*.jpg'; '*.bmp'}, 'Save Simulated Image');

    % Check if the user selected a location
    if ischar(file)
        % Save the simulated image
        imwrite(app.SimulatedImage, fullfile(path, file));
        uialert(app.UIFigure, 'Image saved successfully!', 'Success');
    else
        uialert(app.UIFigure, 'Save operation canceled.', 'Info');
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orignal Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Simulated Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'uploadicon.png');
            app.UploadButton.Text = 'Upload';
            app.WebCamButton = uibutton(app.UIFigure, 'push');
            app.WebCamButton.ButtonPushedFcn = createCallbackFcn(app, @WebCamButtonPushed, true);
            app.WebCamButton.Icon = fullfile(pathToMLAPP, 'webcam.png');
            app.WebCamButton.Text = 'WebCam';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'download.png');
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'circular.png');
            app.ResetButton.Text = 'Reset';
            app.TrichromaticviewButtonGroup = uibuttongroup(app.UIFigure);
            app.TrichromaticviewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrichromaticviewButtonGroupSelectionChanged, true);
            app.TrichromaticviewButtonGroup.Title = 'Trichromatic view';
            app.NormalButton = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.RweakProtanomalyButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.RweakProtanomalyButton_2.Text = 'R-weak/Protanomaly';
            app.GweakDeuteranomalyButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.GweakDeuteranomalyButton_2.Text = 'G-weak/Deuteranomaly';
            app.BweakTritanomalyButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.BweakTritanomalyButton_2.Text = 'B-weak/Tritanomaly';
            app.RBlindProtanomalyButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.RBlindProtanomalyButton_2.Text = 'R-Blind/Protanomaly';
            app.GBlindDeuteranopiaButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.GBlindDeuteranopiaButton_2.Text = 'G-Blind/Deuteranopia';
            app.BBlindTritanopiaButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.BBlindTritanopiaButton_2.Text = 'B-Blind/Tritanopia';
            app.MonochromacyAchromatopsiaButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.MonochromacyAchromatopsiaButton_2.Text = 'Monochromacy/Achromatopsia';
            app.BlueConeMonochromacyButton_2 = uiradiobutton(app.TrichromaticviewButtonGroup);
            app.BlueConeMonochromacyButton_2.Text = 'Blue Cone Monochromacy';
            app.TrichromaticviewLabel = uilabel(app.TrichromaticviewButtonGroup);
            app.TrichromaticviewLabel.Text = 'Trichromatic view';
            app.IntensitySliderLabel = uilabel(app.UIFigure);
            app.IntensitySliderLabel.Text = 'Intensity';
            app.IntensitySlider = uislider(app.UIFigure);
            app.IntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderValueChanged, true);
            app.AnomalousTrichromacyLabel = uilabel(app.UIFigure);
            app.AnomalousTrichromacyLabel.Text = 'Anomalous Trichromacy';
            app.DichromaticViewLabel = uilabel(app.UIFigure);
            app.DichromaticViewLabel.Text = 'Dichromatic View';
            app.MonochromaticviewLabel = uilabel(app.UIFigure);
            app.MonochromaticviewLabel.Text = 'Monochromatic view:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'VisionBlind F.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end